classdef StroopTestApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Button           matlab.ui.control.Button
        Button_2         matlab.ui.control.Button
        Button_3         matlab.ui.control.Button
        Button_4         matlab.ui.control.Button
        Button_5         matlab.ui.control.Button
        Button_6         matlab.ui.control.Button
        Button_7         matlab.ui.control.Button
        NextButton       matlab.ui.control.Button
        CloseButton      matlab.ui.control.Button
        StroopTestLabel  matlab.ui.control.Label
        StartButton      matlab.ui.control.Button
        Label            matlab.ui.control.Label
    end


    properties (Access =public)
        label matlab.ui.control.Label
        closelabel matlab.ui.control.Label
        
        %Record experiment results
        wordtext = strings
        selectedcolor =strings
        
        WORDCOLOR = strings
        WORDTEXT = strings
        SELECTEDCOLOR =strings
        RESULT = strings
        TIME = [];
    end
    
    methods (Access = public)
        
        function createRandomLabel(app)
            %COLORS = ["red","orange","yellow","green","cyan","blue","purple"];
            %color = char(COLORS(randperm(7,1)));
            %word = char(COLORS(randperm(7,1)));
            COLORS = ['r','o','y','g','c','b','p'];
            color = COLORS(randperm(7,1));
            word = COLORS(randperm(7,1));
            app.label = uilabel(app.UIFigure);
            app.label.HorizontalAlignment = 'center';
            app.label.FontSize = 80;
            %app.label.FontColor = color ;
            app.label.Position = [180 149 270 136];
            %app.label.Text = word;
            switch color
                case 'r'
                    app.label.FontColor = 'red';
                    app.WORDCOLOR = [app.WORDCOLOR,'red'];
                case 'o'
                    app.label.FontColor = [1.00,0.41,0.16];
                    app.WORDCOLOR = [app.WORDCOLOR,'orange'];
                case 'y'
                    app.label.FontColor = 'yellow';
                    app.WORDCOLOR = [app.WORDCOLOR,'yellow'];
                case 'g'
                    app.label.FontColor = 'green';
                    app.WORDCOLOR = [app.WORDCOLOR,'green'];
                case 'c'
                    app.label.FontColor = 'cyan';
                    app.WORDCOLOR = [app.WORDCOLOR,'cyan'];
                case 'b'
                    app.label.FontColor = 'blue';
                    app.WORDCOLOR = [app.WORDCOLOR,'blue'];
                case 'p'
                    app.label.FontColor = [0.72,0.27,1.00];
                    app.WORDCOLOR = [app.WORDCOLOR,'purple'];
            end
            switch word
                case 'r'
                    app.label.Text = 'red';
                    app.wordtext='red';
                    app.WORDTEXT = [app.WORDTEXT,'red'];
                case 'o'
                    app.label.Text = 'orange';
                    app.wordtext='orange';
                    app.WORDTEXT = [app.WORDTEXT,'orange'];
                case 'y'
                    app.label.Text = 'yellow';
                    app.wordtext='yellow';
                    app.WORDTEXT = [app.WORDTEXT,'yellow'];
                case 'g'
                    app.label.Text= 'green';
                    app.wordtext='green';
                    app.WORDTEXT = [app.WORDTEXT,'green'];
                case 'c'
                    app.label.Text = 'cyan';
                    app.wordtext='cyan';
                    app.WORDTEXT = [app.WORDTEXT,'cyan'];
                case 'b'
                    app.label.Text = 'blue';
                    app.wordtext='blue';
                    app.WORDTEXT = [app.WORDTEXT,'blue'];
                case 'p'
                    app.label.Text = 'purple';
                    app.wordtext='purple';
                    app.WORDTEXT = [app.WORDTEXT,'purple'];
            end
            %record word color/meaning
            assignin('base','wordcolor',app.WORDCOLOR); 
            assignin('base','wordtext',app.WORDTEXT);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            delete(app.Label)
            createRandomLabel(app)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            delete(app.label)
            createRandomLabel(app)
            tic
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app.label)
            app.closelabel = uilabel(app.UIFigure);
            app.closelabel.HorizontalAlignment = 'center';
            app.closelabel.FontSize = 20;
            app.closelabel.Position = [94 133 454 165];
            app.closelabel.Text = "Thank you for your participation!";
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %record reaction time
            toc;
            time = toc;
            app.TIME = [app.TIME,time]; 
            assignin('base','TIME',app.TIME);
            
            % Record the color of the button selected by the participant
            app.selectedcolor = 'red';
            app.SELECTEDCOLOR = [app.SELECTEDCOLOR, app.selectedcolor];
            if strcmp(app.selectedcolor,app.wordtext)
                app.RESULT = [app.RESULT,'CORRECT'];
            else
                app.RESULT = [app.RESULT,'INCORRECT'];
            end
            assignin('base','result',app.RESULT);
            assignin('base','selectedcolor',app.SELECTEDCOLOR);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %record reaction time
            toc;
            time = toc;
            app.TIME = [app.TIME,time]; 
            assignin('base','TIME',app.TIME);
            
            % Record the color of the button selected by the participant
            app.selectedcolor = 'orange';
            app.SELECTEDCOLOR = [app.SELECTEDCOLOR, app.selectedcolor];
            if strcmp(app.selectedcolor,app.wordtext)
                app.RESULT = [app.RESULT,'CORRECT'];
            else
                app.RESULT = [app.RESULT,'INCORRECT'];
            end
            assignin('base','result',app.RESULT);
            assignin('base','selectedcolor',app.SELECTEDCOLOR);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %record reaction time
            toc;
            time = toc;
            app.TIME = [app.TIME,time]; 
            assignin('base','TIME',app.TIME);
            
            % Record the color of the button selected by the participant
            app.selectedcolor = 'yellow';
            app.SELECTEDCOLOR = [app.SELECTEDCOLOR, app.selectedcolor];
            if strcmp(app.selectedcolor,app.wordtext)
                app.RESULT = [app.RESULT,'CORRECT'];
            else
                app.RESULT = [app.RESULT,'INCORRECT'];
            end
            assignin('base','result',app.RESULT);
            assignin('base','selectedcolor',app.SELECTEDCOLOR);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %record reaction time
            toc;
            time = toc;
            app.TIME = [app.TIME,time]; 
            assignin('base','TIME',app.TIME);
            
            % Record the color of the button selected by the participant
            app.selectedcolor = 'green';
            app.SELECTEDCOLOR = [app.SELECTEDCOLOR, app.selectedcolor];
            if strcmp(app.selectedcolor,app.wordtext)
                app.RESULT = [app.RESULT,'CORRECT'];
            else
                app.RESULT = [app.RESULT,'INCORRECT'];
            end
            assignin('base','result',app.RESULT);
            assignin('base','selectedcolor',app.SELECTEDCOLOR);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            %record reaction time
            toc;
            time = toc;
            app.TIME = [app.TIME,time]; 
            assignin('base','TIME',app.TIME);
            
            % Record the color of the button selected by the participant
            app.selectedcolor = 'cyan';
            app.SELECTEDCOLOR = [app.SELECTEDCOLOR, app.selectedcolor];
            if strcmp(app.selectedcolor,app.wordtext)
                app.RESULT = [app.RESULT,'CORRECT'];
            else
                app.RESULT = [app.RESULT,'INCORRECT'];
            end
            assignin('base','result',app.RESULT);
            assignin('base','selectedcolor',app.SELECTEDCOLOR);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            %record reaction time
            toc;
            time = toc;
            app.TIME = [app.TIME,time]; 
            assignin('base','TIME',app.TIME);
            
            % Record the color of the button selected by the participant
            app.selectedcolor = 'blue';
            app.SELECTEDCOLOR = [app.SELECTEDCOLOR, app.selectedcolor];
            if strcmp(app.selectedcolor,app.wordtext)
                app.RESULT = [app.RESULT,'CORRECT'];
            else
                app.RESULT = [app.RESULT,'INCORRECT'];
            end
            assignin('base','result',app.RESULT);
            assignin('base','selectedcolor',app.SELECTEDCOLOR);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            %record reaction time
            toc;
            time = toc;
            app.TIME = [app.TIME,time]; 
            assignin('base','TIME',app.TIME);
            
            % Record the color of the button selected by the participant
            app.selectedcolor = 'purple';
            app.SELECTEDCOLOR = [app.SELECTEDCOLOR, app.selectedcolor];
            if strcmp(app.selectedcolor,app.wordtext)
                app.RESULT = [app.RESULT,'CORRECT'];
            else
                app.RESULT = [app.RESULT,'INCORRECT'];
            end
            assignin('base','result',app.RESULT);
            assignin('base','selectedcolor',app.SELECTEDCOLOR);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.StroopTestLabel = uilabel(app.UIFigure);
            app.StroopTestLabel.Text = 'Stroop Test ';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'When you see the word displayed on the screen, '; 'please select the button with the same color '; 'as the word meaning from the buttons below.'; 'Click''start'' to enter the experiment.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StroopTestApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end